classdef WebCamPixelater < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        RunButton       matlab.ui.control.Button
        SaveButton      matlab.ui.control.Button
        PixelaterLabel  matlab.ui.control.Label
        DropDown        matlab.ui.control.DropDown
        ColorPicker     matlab.ui.control.ColorPicker
        UIAxes          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Webcam         % webcam object
        Play = false   % video play request
        Save = false   % save frame request
        Close = false  % app close request
        Folder = pwd   % store last folder location and use as default
        Hue (1,1)      % Hue from color picker
        Limits (1,2)   % Saturation and Value limits
        Background     % webcam background image for cloak and chroma
        Galaxy         % galaxy background image for cloak and chroma
        Frames         % store frames to correct hologram mirroring on save
        RunButtonText  % used to protect against missing icons
        RunButtonIcon  % used to protect against missing icons
    end

    methods (Access = private)

        function processVideo( app )
            n = 60; % number of frames to buffer (~3 seconds)
            img = app.UIAxes.Children.CData;
            frames = zeros( [size(img) n], class(img) );
            idx = round( linspace( n, 1, 10 ) ); % 10 frames for "ghost"

            try
                while app.Play
                    img = snapshot( app.Webcam );
                    img = fliplr( img );
                    frames = circshift( frames, 1, 4 );
                    frames(:,:,:,1) = img; % store current frame

                    if (app.DropDown.Value == "chroma")
                        % Create color isolation mask and 
                        % replace all other pixels to gray-scale
                        mask = ~colorMask( img, app.Hue, ...
                            HueTol=0.05, SatValLim=app.Limits );
                        mask = repmat( mask, [1 1 3] ); % apply to 3 channels
                        gry = repmat( im2gray( img ), [1 1 3] );
                        img(mask) = gry(mask);

                    elseif (app.DropDown.Value == "cloak")
                        % Create color isolation mask (using hue) and
                        % replace isolated color with background image
                        mask = colorMask( img, app.Hue, ...
                            HueTol=0.09, SatValLim=app.Limits );
                        mask = repmat( mask, [1 1 3] ); % apply to 3 channels
                        img(mask) = app.Background(mask);

                    elseif (app.DropDown.Value == "ghost")
                        % Note: idx goes from n to 1
                        img = frames(:,:,:,idx(1)); % oldest frame, first background
                        for k = idx(2:end) % end with newest frame as final foreground
                            img = imblend( frames(:,:,:,k), img, ForegroundOpacity=0.7 );
                        end

                    elseif (app.DropDown.Value == "hologram")
                        % 3D image for horizontal motion when wearing red-cyan glasses
                        % (with red-cyan lens over left-right eyes respectively)
                        % separate images by two frames (~0.1 seconds)
                        img = anaglyph( frames(:,:,:,1), frames(:,:,:,3), Motion=true );

                    else
                        img = imeffects( img, app.DropDown.Value );
                    end
                    
                    app.UIAxes.Children.CData = img;
                    drawnow limitrate; % ~20 fps
                end

            catch ME
                selection = uiconfirm( app.UIFigure, ME.message, "Error", ...
                    Interpreter="html", Options="Close", Icon="error" ); %#ok<NASGU>
                delete( app ); % after user confirms by returning output
                return
            end

            % Update background image with webcam frame or galaxy image
            if (app.DropDown.Value == "none")
                app.Background = app.UIAxes.Children.CData;
            elseif (app.DropDown.Value == "custom")
                app.Background = app.Galaxy;
            end
            app.Frames = frames(:,:,:,[1,3]); % store for saving hologram
            
            if app.Save
                saveImage( app );
                app.RunButtonPushed(); % restart video feed loop
            elseif app.Close
                delete( app )
            end
        end

        function saveImage( app )
            % Prompt user for save location
            fileName = "pixelater_" + app.DropDown.Value + ".png"; % default value
            [fileName,filePath] = uiputfile( fullfile( app.Folder, fileName ) );
            focus( app.UIFigure ) % restore app focus

            if (app.DropDown.Value == "hologram")
                % Recreate hologram with un-mirrored images
                img = anaglyph( fliplr( app.Frames(:,:,:,1) ), ...
                    fliplr( app.Frames(:,:,:,end) ), Motion=true );
            else
                img = fliplr( app.UIAxes.Children.CData ); % undo mirroring
            end

            if isequal( fileName, 0 )
                % User canceled file save, assign to workspace instead
                varName = "pix_" + string( datetime( "now", Format="HHmmss" ) );
                assignin( "base", varName, img )
            else
                % Save image
                imwrite( img, fullfile( filePath, fileName ) );
                app.Folder = filePath;  % update default save folder
            end
            app.Save = false;
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui( app.UIFigure, "center" )
            % assignin( "base", "app", app ) % debug

            % Setup icons
            try % this should work for R2022a or newer
                iconPath = fullfile( matlabroot, "ui", "icons", "24x24" );
                app.RunButtonText = ["Play","Pause"];
                app.RunButtonIcon = [fullfile( iconPath, "video.svg" ), ...
                    fullfile( iconPath, "pause.svg" )];
                app.RunButton.Text = app.RunButtonText(1);
                app.RunButton.Icon = app.RunButtonIcon(1);
                app.SaveButton.Icon = fullfile( iconPath, "unsaved.svg" );
                app.SaveButton.Text = "Save";
            catch % but just in case...
                app.RunButtonText = ["▶️ Play","⏸️ Pause"];
                app.RunButtonIcon = ["",""];
            end

            try
                camlist = webcamlist;
            catch ME
                msg = "Webcam Pixelater requires the <html><a href=""" + ...
                    "matlab:matlab.internal.addons.launchers.showExplorer(" + ...
                    "'ErrorRecovery','identifier','USBWEBCAM','focused','webcamlist')" + ...
                    """>MATLAB Support Package for USB Webcams</a></html>." + newline + ...
                    newline + "Please close the app, install the support package, and try again.";
                selection = uiconfirm( app.UIFigure, msg, "Error", ...
                    Interpreter="html", Options="Close", Icon="error" ); %#ok<NASGU>
                delete( app ); % after user confirms by returning output
                return
            end

            try
                % Setup Webcam
                opts = [camlist; "Close"];
                msg = "Please choose from the following options:";
                selection = uiconfirm( app.UIFigure, msg, ...
                    "Select Webcam", Options=opts );
                if (selection == opts(end))
                    delete( app );
                    return
                end

                % Setup axes and test imeffects call
                app.Webcam = webcam( selection );
                img = snapshot( app.Webcam );
                img = fliplr( img );
                img = imeffects( img, "none" );
                imshow( img, Parent=app.UIAxes )
                axis( app.UIAxes, "image")
                disableDefaultInteractivity( app.UIAxes )
                app.UIAxes.Toolbar.Visible = "off";

            catch ME
                msg = string( ME.message ) + newline + newline + ...
                    "Webcam Pixelater requires files from the <html><a href=" + ...
                    "'https://www.mathworks.com/matlabcentral/fileexchange/87452'" + ...
                    ">Pixels to Pictures Supplemental Material</a></html>." + ...
                    newline + newline + "Please ensure these files are available, " + ...
                    "the 'P2P-Supplement.prj' project is open, and try again.";
                selection = uiconfirm( app.UIFigure, msg, "Error", ...
                    Interpreter="html", Options="Close", Icon="error" ); %#ok<NASGU>
                delete( app ); % after user confirms by returning output
                return
            end

            % Set default values
            hsv = [2.6/6 0.5 0.5]; % 2.6/6 ~ green/cyan
            app.ColorPicker.Value = hsv2rgb( hsv );
            app.ColorPickerValueChanged();
            try %#ok<TRYNC>
                % Read phantom galaxy image from ESA web page
                phantom = imread( "https://cdn.esahubble.org" ...
                    + "/archives/images/screen/potw2235a.jpg" );
                img = imresize( phantom, size( img, 1:2 ) );
            end
            app.Background = img; % store as background image
            app.Galaxy = img;     % store for reuse using "custom"
            clear addAnimation % clear persistent variables (in case file changed)

            % Add "gotham" effect if Computer Vision is available
            hasCompVision = license( "test", "video_and_image_blockset" );
            items = string( app.DropDown.Items );
            if logical( hasCompVision )
                items = [items(1), sort( ["gotham", items(2:(end-1))] ), items(end)];
            end

            % Enable components and push run button
            app.DropDown.Items = items;
            app.DropDown.Enable = "on";
            app.RunButton.Enable = "on";
            app.SaveButton.Enable = "on";
            app.RunButtonPushed(); % start video feed loop
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.Play = ~app.Play; % toggle play status
            if app.Play
                app.RunButton.Text = app.RunButtonText(2);
                app.RunButton.Icon = app.RunButtonIcon(2);
                processVideo( app ); % start video feed loop
            else
                app.RunButton.Text = app.RunButtonText(1);
                app.RunButton.Icon = app.RunButtonIcon(1);
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if app.Play
                app.Save = true;  % trigger save when loop stops
                app.Play = false; % stop video feed loop
            else
                saveImage( app );
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            if ismember( app.DropDown.Value, ["chroma","cloak"] )
                app.ColorPicker.Enable = "on";
            else
                app.ColorPicker.Enable = "off";
            end
        end

        % Callback function: ColorPicker
        function ColorPickerValueChanged(app, event)
            hsv = rgb2hsv( app.ColorPicker.Value );
            app.Hue = hsv(1);

            % Set Saturation and Value limits
            if isbetween( app.Hue, 0.25, 0.5 ) % = [1.5 3]/6
                % hue = (0:6)/6 = R-Y-G-C-B-M-R
                % if hue is near green, allow lighter and darker shades
                app.Limits = [0.2, 0.2];
            else
                % tighten rejection of light and dark shades
                app.Limits = [0.5, 0.3];
            end

            if any( hsv(2:3) < app.Limits )
                msg = "The 'chroma' and 'cloak' effects work best when " ...
                    + "using vibrant colors. Near-white or near-black " ...
                    + "shades are not recommended.";
                uialert( app.UIFigure, msg, "Color Picker", Icon="warning" )
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.Close = true; % trigger close when loop stops
            if app.Play
                app.Play = false; % stop loop before exiting
            else
                delete( app );
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Webcam Pixelater';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'pixelater_24.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.ColorPicker = uicolorpicker(app.GridLayout);
            app.ColorPicker.Value = [0 1 0];
            app.ColorPicker.ValueChangedFcn = createCallbackFcn(app, @ColorPickerValueChanged, true);
            app.ColorPicker.Enable = 'off';
            app.DropDown = uidropdown(app.GridLayout);
            app.DropDown.Items = {'none', 'barrel', 'blur', 'chroma', 'cloak', 'comic', 'edge', 'ghost', 'grayscale', 'hologram', 'hyperspace', 'kaleidoscope', 'matrix', 'negative', 'neon', 'pencil', 'pincushion', 'pixelate', 'quantize', 'thermal', 'wave', 'custom'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Enable = 'off';
            app.DropDown.Value = 'none';
            app.PixelaterLabel = uilabel(app.GridLayout);
            app.PixelaterLabel.Text = 'Select Pixelater Effect:';
            app.SaveButton = uibutton(app.GridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = '💾 Save';
            app.RunButton = uibutton(app.GridLayout, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = '▶️ Play';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WebCamPixelater

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end